classdef DataTransferGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataTypeforTransferListBox  matlab.ui.control.ListBox
        DataTypeforTransferListBoxLabel  matlab.ui.control.Label
        SynchronizeDataButton       matlab.ui.control.Button
        LoadOptionsButton           matlab.ui.control.Button
        VersioncontrolButton        matlab.ui.control.StateButton
        TransferTypeButtonGroup     matlab.ui.container.ButtonGroup
        BidirectionalButton         matlab.ui.control.ToggleButton
        UnidirectionalButton        matlab.ui.control.ToggleButton
        LocationBListBox            matlab.ui.control.ListBox
        LocationBListBoxLabel       matlab.ui.control.Label
        LocationAListBox            matlab.ui.control.ListBox
        LocationAListBoxLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOptionsButton
        function LoadOptionsButtonPushed(app, event)
            global availableDrives availablePaths optionNames  
            % set the name 'transform' from list to file location
            optionNames.permanent = 'Local Drive';
            optionNames.portable = 'On The Go Data';
            optionNames.server = 'Server';
            optionNames.other = 'Other';
            
            % Set the names into the appropriate locations
            [availableDrives, availablePaths] = getDataFolderOptions;
            optionsList = cell(1,1);
            optionsList{1} = optionNames.other;
            % Find the available drives from the above function
            if availableDrives.permanent
                optionsList{end+1} = optionNames.permanent;
            end
            if availableDrives.portable
                optionsList{end+1} = optionNames.portable;
            end 
            if availableDrives.server
                optionsList{end+1} = optionNames.server;
            end 
            
            % Update the list options for the available drives: 
            app.LocationAListBox.Items = optionsList;
            app.LocationAListBox.Value = optionNames.other;

            app.LocationBListBox.Items = optionsList;
            app.LocationBListBox.Value = optionNames.other;

            % Update the DATA TYPE list box
            optionNames.singletrial = 'Single Trial';
            optionNames.rawdata = 'Raw Data';
            optionNames.groupedTrials = 'Grouped Trials';
            optionNames.groupComparison = 'Group Comparisions';
            optionNames.othertype = 'Other';
            
            dataOptionList = cell(5,1);
            dataOptionList{1} = optionNames.rawdata;
            dataOptionList{2} = optionNames.singletrial;
            dataOptionList{3} = optionNames.groupedTrials;
            dataOptionList{4} = optionNames.groupComparison;
            dataOptionList{5} = optionNames.othertype;

            app.DataTypeforTransferListBox.Items = dataOptionList;

        end

        % Button pushed function: SynchronizeDataButton
        function SynchronizeDataButtonPushed(app, event)
            global availablePaths optionNames
            loc_A = app.LocationAListBox.Value;
            % pull the start locations for the data transfer:                 
            switch loc_A
                case optionNames.permanent
                    rootPath_A = availablePaths.permanentPath;
                case optionNames.server
                    rootPath_A = availablePaths.serverPath;
                case optionNames.portable
                    rootPath_A = availablePaths.onthegoPath;
                case optionNames.other
                   temp = uigetdir('','Select drive location A');
                   rootPath_A = [temp '/'];
            end
            
            % pull the end locations for the data transfer: 
            loc_B = app.LocationBListBox.Value;
            switch loc_B
                case optionNames.permanent
                    rootPath_B = availablePaths.permanentPath;
                case optionNames.server
                    rootPath_B = availablePaths.serverPath;
                case optionNames.portable
                    rootPath_B = availablePaths.onthegoPath;
                case optionNames.other
                   temp = uigetdir('','Select drive location B');
                   rootPath_B = [temp '/'];
            end

            % Assemble the full file type 
            pathExtensions = getPathNames;
            switch app.DataTypeforTransferListBox.Value
                case optionNames.singletrial
                    type_str_A = pathExtensions.single_trial;
                    type_str_B = pathExtensions.single_trial;
                case optionNames.rawdata
                    type_str_A = pathExtensions.raw_data;
                    type_str_B = pathExtensions.raw_data;
                case optionNames.groupedTrials
                    type_str_A = pathExtensions.grouped_trials;
                    type_str_B = pathExtensions.grouped_trials;
                case optionNames.groupComparison
                    type_str_A = pathExtensions.group_comparision;
                    type_str_B = pathExtensions.group_comparision;
                case optionNames.othertype
                    type_str_A = [uigetdir(rootPath_A,'Select the start folder'), '/'];
                    type_str_B = [uigetdir(rootPath_B,'Select the destination folder'), '/'];
            end

            if strcmp(rootPath_A, type_str_A) % same folder selected for root and main folder type
                path_A = rootPath_A;
            else
                path_A = [rootPath_A, type_str_A];
            end
            if strcmp(rootPath_B, type_str_B) % same folder selected for root and main folder type
                path_B = rootPath_B;
            else
                path_B = [rootPath_B, type_str_B];
            end
            
            % Display the selected folders:
            disp('Selected location A: ')
            disp(path_A)
            disp('Selected location B: ')
            disp(path_B)

            different_target_folder_names = false;
    
            % Determine what direction the data is being transfered
            switch app.TransferTypeButtonGroup.SelectedObject.Text
                case 'Unidirectional'
                   bi_direction = false;
                case 'Bidirectional'
                   bi_direction = true;
            end
            
            % Select possible subfolders to transfer:
            promptString = ['Select folder(s) to sync FROM ' loc_A ' TO ' loc_B];
            folders_in_A = selectFolder(path_A,'mulitple',promptString); 
            % (if no subfolders selected or exist, give option for primary folder to be the selected folder)
            if isempty(folders_in_A) % this would be a single folder transfer option-- and the only place
                % that could have two different end directory names
                quest_str = ['No folders were selected in location A (' path_A '), choose your action:'];
                switch questdlg(quest_str, 'Start Location', 'Use the current folder', 'Cancel', 'Cancel')
                    case 'Use the current folder'
                        % code up a rewind to the latest folder as the folders_in_A
                        temp_path = strsplit(path_A, {'/', '\'});
                        newPath_A = [];
                        for i = 1:length(temp_path)-2
                            newPath_A = [newPath_A, temp_path{i} '/'];
                        end
                        path_A = newPath_A;
                        folders_in_A = temp_path(end-1);
                        % check if the target folder has the same folder name issue:
                        if ~bi_direction
                            curr_target_dir = [path_B folders_in_A{:}];
                            quest2_str = ['This is the current target folder, ' curr_target_dir ' use this path or up one level?'];
                                switch questdlg(quest2_str,'Targe Location Selection','Current path', 'Up one level','Cancel','Current path')
                                    case 'Current path' %just continue in the this case
                                    case 'Up one level'
                                        % code up a rewind to the latest folder
                                        different_target_folder_names = true;
                                    case 'Cancel'
                                        return
                                end
                        end
                    case 'Cancel'
                        return
                end
            end
            % still need a way to make this work for a single folder sync
            % where both have different folder names
            if bi_direction
                promptString = ['Select folder(s) to sync FROM ' loc_B ' TO ' loc_A];
                folders_in_B = selectFolder(path_B,'mulitple',promptString); 
                % (if no subfolders selected or exist, give option for primary folder to
                % be the selected folder)
                if isempty(folders_in_B)
                    quest_str = ['No folders were selected in location B (' path_B '), choose your action:'];
                    switch questdlg(quest_str, 'Start Location', 'Use the root folder', 'Cancel', 'Cancel')
                        case 'Use the root folder'
                            % code up a rewind to the latest folder as the folders_in_A
                            temp_path = strsplit(path_B, {'/', '\'});
                            newPath_B = [];
                            for i = 1:length(temp_path)-2
                                newPath_B = [newPath_B, temp_path{i}, '/'];
                            end
                            path_B = newPath_B;
                            folders_in_B = temp_path(end-1);
                        case 'Cancel'
                            return
                    end
                end
            else 
                folders_in_B = [];
            end
            

            % exit the process
            if isempty(folders_in_A) && isempty(folders_in_B)
                warndlg('No folders selected for synchronization')
                return
            end

            % check for version control
            version_control = app.VersioncontrolButton.Value;

            n = length(folders_in_A) + length(folders_in_B); % total number of folders to sync
            f = waitbar(0,['Syncing from ' loc_A ' to ' loc_B '...0/' num2str(n)]); %setup a loading status bar
            idx = 0;

            % ============== GOING FROM A to B ==============
            folderSelected = folders_in_A;
            TO_base = path_B;
            FROM_base = path_A;
            for i = 1:length(folderSelected)
                disp(['Syncing data from '  folderSelected{i} ' ...'])
                targetPath = [TO_base, folderSelected{i} '/'];
                startPath = [FROM_base, folderSelected{i} '/'];
                % check for single folder transfer from different locations
                if different_target_folder_names && (length(folderSelected)==1)
                    targetPath = path_B;
                end
                % check that the start folder actually exists (if the root folder doesn't exist in unidirection, 
                % then cancel, else, make the folder for the other direction pass)
                if ~isfolder(startPath) 
                    if bi_direction
                        mkdir(startPath);
                    else
                        warndlg('Selected folder to transfer data from does not exist, check path')
                        disp(startPath)
                    end
                end
                % check that the target folder exists -- if it doesn't, create it:
                 if ~isfolder(targetPath)
                        mkdir(targetPath);
                 end  

                % COPY MISSING FILES:
                success = SyncFolderandSubfolder(startPath, targetPath,version_control);
                % check for copy failures
                if ~success
                    warndlg(['Error in copying ' folderSelected{i}])
                    return
                end       
                % UPDATE THE WAITBAR
                idx = idx+1;
                waitbar(idx/n, f, ['Syncing from ' loc_A ' to ' loc_B '...' num2str(idx) '/' num2str(n)])
            end

            % ============ GOING FROM B to A ==============
            if bi_direction
                folderSelected = folders_in_B;
                TO_base = path_A;
                FROM_base = path_B;
                for i = 1:length(folderSelected)
                    disp(['Syncing data from '  folderSelected{i} ' ...'])
                    targetPath = [TO_base, folderSelected{i} '/'];
                    startPath = [FROM_base, folderSelected{i} '/'];
                    success = SyncFolderandSubfolder(startPath, targetPath,version_control);
                    % check for copy failures
                    if ~success
                        warndlg(['Error in copying ' folderSelected{i}])
                        return
                    end       
                    % update waitbar
                    idx = idx+1;
                    waitbar(idx/n, f, ['Syncing from ' loc_B ' to ' loc_A '...' num2str(idx) '/' num2str(n)])
                end
            end
            close(f)
            disp('All files finished synchronizing')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LocationAListBoxLabel = uilabel(app.UIFigure);
            app.LocationAListBoxLabel.Text = 'Location A';
            app.LocationAListBox = uilistbox(app.UIFigure);
            app.LocationAListBox.Items = {'Other'};
            app.LocationAListBox.Value = 'Other';
            app.LocationBListBoxLabel = uilabel(app.UIFigure);
            app.LocationBListBoxLabel.Text = 'Location B';
            app.LocationBListBox = uilistbox(app.UIFigure);
            app.LocationBListBox.Items = {'Other'};
            app.LocationBListBox.Value = 'Other';
            app.TransferTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TransferTypeButtonGroup.Title = 'Transfer Type';
            app.UnidirectionalButton = uitogglebutton(app.TransferTypeButtonGroup);
            app.UnidirectionalButton.Text = 'Unidirectional';
            app.UnidirectionalButton.Value = true;
            app.BidirectionalButton = uitogglebutton(app.TransferTypeButtonGroup);
            app.BidirectionalButton.Text = 'Bidirectional';
            app.VersioncontrolButton = uibutton(app.UIFigure, 'state');
            app.VersioncontrolButton.Text = 'Version control';
            app.LoadOptionsButton = uibutton(app.UIFigure, 'push');
            app.LoadOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptionsButtonPushed, true);
            app.LoadOptionsButton.Text = 'Load Options';
            app.SynchronizeDataButton = uibutton(app.UIFigure, 'push');
            app.SynchronizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeDataButtonPushed, true);
            app.SynchronizeDataButton.Text = 'Synchronize Data';
            app.DataTypeforTransferListBoxLabel = uilabel(app.UIFigure);
            app.DataTypeforTransferListBoxLabel.Text = 'Data Type for Transfer';
            app.DataTypeforTransferListBox = uilistbox(app.UIFigure);
            app.DataTypeforTransferListBox.Items = {'Other'};
            app.DataTypeforTransferListBox.Value = 'Other';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataTransferGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end